classdef PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        xyEditField_2Label  matlab.ui.control.Label
        a2                  matlab.ui.control.NumericEditField
        y2EditFieldLabel    matlab.ui.control.Label
        a3                  matlab.ui.control.NumericEditField
        xEditFieldLabel     matlab.ui.control.Label
        a4                  matlab.ui.control.NumericEditField
        yEditFieldLabel     matlab.ui.control.Label
        a5                  matlab.ui.control.NumericEditField
        a6                  matlab.ui.control.NumericEditField
        Label_2             matlab.ui.control.Label
        Button              matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        vectors             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        type                matlab.ui.control.Label
        x2Label             matlab.ui.control.Label
        a1                  matlab.ui.control.NumericEditField
        canonic             matlab.ui.control.Label
        center              matlab.ui.control.Label
        vectors_2           matlab.ui.control.Label
        CheckBox            matlab.ui.control.CheckBox
        f3v                 matlab.ui.control.Label
        Axes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a = app.a1.Value;
            b = app.a2.Value;
            c = app.a3.Value;
            d = app.a4.Value;
            e = app.a5.Value;
            f = app.a6.Value;
            app.canonic.Text = "";
            if (a^2 + b^2 + c^2) == 0
                errordlg('Такой кривой не существует')
            else
                B = [a b/2; b/2 c];
                [e_vec,e_num] = eig(B);

                if e_num(1,1)>0 && e_num(2,2)>0 
                    app.type.Text  = "Эллипс";
                    k=0;
                end
                if (e_num(1,1)>0 && e_num(2,2)<0) || (e_num(1,1)<0 && e_num(2,2)>0)
                    if det([a b/2 d/2;
                            b/2 c e/2; 
                            d/2 e/2 f])==0
                    
                            app.type.Text = "Пара пересекающихся прямых";
                            k=3;
   
                    else
                            app.type.Text  = "Гипербола";
                            k = 0;
                    end
                    
                end
                if (e_num(1,1)==0 && e_num(2,2)~=0) || (e_num(1,1)~=0 && e_num(2,2)==0)
                    if det([a b/2 d/2;
                            b/2 c e/2; 
                            d/2 e/2 f])==0 && (det([a d/2; d/2 f])+det([c e/2; e/2 f]))<0
                        app.type.Text = "Пара параллельных прямых";
                        k=2;
                    else
                    app.type.Text = "Парабола";
                    k = 1;
                    end
                end
                syms x y x1 y1 x2 y2 xf yf x3 y3 xt yt t
                
                X1Y1=[x1;y1];
                XY = e_vec*X1Y1;
                x = XY(1);
                y = XY(2);
                f_original = a*xf^2 +b*xf*yf + c*yf^2 +d*xf + e*yf + f;
                f_first = a*x^2 +b*x*y + c*y^2 +d*x + e*y + f;
                f_new = simplify(f_first);
                fimplicit(app.Axes, f_original, [-10 10 -10 10]);
                hold(app.Axes, 'on');
                cxy = coeffs(f_new, [x1 y1]);
                if (length(cxy)==5) 
                      f_2=-1*((x3+(cxy(4)/(2*cxy(5))))^(2)/((cxy(1)-((cxy(4))^2)/(4*cxy(5))-((cxy(2))^2)/(4*cxy(3)))/cxy(5))+(y3+(cxy(2)/(2*cxy(3))))^(2)/((cxy(1)-((cxy(4))^2)/(4*cxy(5))-((cxy(2))^2)/(4*cxy(3)))/cxy(3)));
                      f_3=(cxy(1)-((cxy(4))^2)/(4*cxy(5))-((cxy(2))^2)/(4*cxy(3)))/(cxy(1)-((cxy(4))^2)/(4*cxy(5))-((cxy(2))^2)/(4*cxy(3)));
                      xC=-(x+(cxy(4)/(2*cxy(5))));
                      yC = (y+(cxy(2)/(2*cxy(3))));
                      D = solve([xC==0 yC==0],x1,y1);
                      R = [D.x1, D.y1];
                      
                      plot(app.Axes, D.x1,D.y1, "ro");
                      
                      line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                
                end
                if (length(cxy)==4) && f==0 
                      f_2=((x3+(cxy(3)/2*cxy(4)))^2/cxy(2))+((y3+(cxy(1)/2*cxy(2)))^2/cxy(4));
                      f_3= (((cxy(3))^2*cxy(2))+((cxy(1))^2*cxy(4)))/(4*(cxy(4)*cxy(2))^2);
                      xC= x+(cxy(3)/(2*cxy(4)));
                      yC = y+(cxy(1)/(2*cxy(2)));
                       
                      D = solve([xC==0 yC==0],x1,y1);
                      R = [D.x1, D.y1];
                      
                      plot(app.Axes, D.x1,D.y1, "ro");
                      
                      
                      line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                      line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                
                end
                
                if (length(cxy)==4) && k ==1 
                      if c ~= 0
                        f_2=(y3+cxy(2)/(2*cxy(3)))^2-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(x3+cxy(1)/cxy(4));
                        f_3 = 0;
                        
                        xC = -(-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(x+cxy(1)/cxy(4)));
                        yC = y+cxy(2)/(2*cxy(3));
                        D = solve([xC==0 yC==0],x1,y1);
                        R = [D.x1, D.y1];
                        plot(app.Axes, D.x1,D.y1, "ro");
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                        
                      elseif (a ~= 0)
                        f_2=(x3+cxy(2)/(2*cxy(3)))^2-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(y3+cxy(1)/cxy(4));
                        f_3 = 0;
                        
                        xC = -(-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(x+cxy(1)/cxy(4)));
                        yC = y+cxy(2)/(2*cxy(3));
                        D = solve([xC==0 yC==0],x1,y1);
                        R = [D.x1, D.y1];
      
                      
                        plot(app.Axes, D.x1,D.y1, "ro");
                      
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                
                     end
                end
                if (length(cxy)==3) && d ==0 && e == 0
                    f_2=((x3^2)/cxy(2))+ ((y3^2)/cxy(3));
                    f_3 = -cxy(1)/cxy(2)*cxy(3);
                    
                    xC = x;
                    yC = y;
                    D = solve([xC==0 yC==0],x1,y1);
                    R = [D.x1, D.y1];
                    plot(app.Axes, D.x1,D.y1, "ro");
                    line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                end
                
                if (length(cxy) == 2) && (k == 1)
                    cxy = [cxy 0 ];
                    f_2=((x3^2)/cxy(2))+ ((y3^2)/cxy(3));
                    f_3 = -cxy(1)/cxy(2)*cxy(3);
                    
                    xC = x;
                    yC = y;
                    D = solve([xC==0 yC==0],x1,y1);
                    R = [D.x1, D.y1];
                    plot(app.Axes, D.x1,D.y1, "ro");
                    line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                    line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r", "LineStyle", "--");
                end
                    if (k==2)
    
                        if (length(cxy)==2)
                            if a==0 && f~=0
                               f_2 = -(y3^2)/(cxy(1)/cxy(2));
                               f_3 = 1;
                               xC = x3;
                               yC = y3;
                             end
                             if a==0 && f==0
                               f_2 = ((y3+cxy(1)/(2*cxy(2))))^2;
                               f_3 = 0;
                               xC = x3+cxy(1)/(2*cxy(2));
                               yC = y3;
                             end
                             if c==0 && f~=0
                               f_2 = -(x3^2)/(cxy(1)/cxy(2));
                               f_3 = 1;
                               xC = x3;
                               yC = y3;
                             end
                             if c==0 && f==0
                               f_2 = (x3+cxy(1)/(2*cxy(2)))^2;
                               f_3 = 0;
                               xC = x3+cxy(1)/(2*cxy(2));
                               yC = y3;
                             end
                        end
                        if (length(cxy)==3)
                            if a == 0
                            f_2=(y3 + cxy(2)/(2*cxy(3)))^2/((cxy(2)/(2*cxy(3)))^2 - cxy(1)/cxy(3));
                            f_3 = 1;
                            xC = (x3 + cxy(2)/(2*cxy(3)));
                            yC = y3;
                            
                            end
                            if c == 0
                            f_2=(x3 + cxy(2)/(2*cxy(3)))^2/((cxy(2)/(2*cxy(3)))^2 - cxy(1)/cxy(3));
                            f_3 = 1;
                            xC = (x3 + cxy(2)/(2*cxy(3)));
                            yC = y3;
                            end
                        end
                        
                            D = solve([xC==0,yC ==0 ],x3,y3);
                            R = [D.x3, D.y3];
                            plot(app.Axes, D.x3,D.y3, "ro")
                            line(app.Axes, [D.x3 D.x3+10*e_vec(1,1)], [D.y3 D.y3+10*e_vec(2,1)], "color", "r")
                            line(app.Axes, [D.x3 D.x3-10*e_vec(1,1)], [D.y3 D.y3-10*e_vec(2,1)], "color", "r")
                            line(app.Axes, [D.x3 D.x3+10*e_vec(1,2)], [D.y3 D.y3+10*e_vec(2,2)], "color", "r")
                            line(app.Axes, [D.x3 D.x3-10*e_vec(1,2)], [D.y3 D.y3-10*e_vec(2,2)], "color", "r")
                    end
                    if k==3 
                        if length(cxy)==4
                        f_2=(y3+cxy(2)/(2*cxy(3)))^2-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(x3^2+cxy(1)/cxy(4));
                        f_3 = 0;
                        xC = -(-(cxy(2)^2)/(4*cxy(3))+cxy(4)*(x+cxy(1)/cxy(4)));
                        yC = y+cxy(2)/(2*cxy(3));
                        end
                        if k==3 && length(cxy)==2
                        f_2 = x3^2/c - y3^2/a;
                        f_3 = 0;
                        xC = x;
                        yC = y;
                        end
                        D = solve([xC==0 yC==0],x1,y1);
                        R = [D.x1, D.y1];
                        plot(app.Axes, D.x1,D.y1, "ro")
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,1)], [D.y1 D.y1+10*e_vec(2,1)], "color", "r")
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,1)], [D.y1 D.y1-10*e_vec(2,1)], "color", "r")
                        line(app.Axes, [D.x1 D.x1+10*e_vec(1,2)], [D.y1 D.y1+10*e_vec(2,2)], "color", "r")
                        line(app.Axes, [D.x1 D.x1-10*e_vec(1,2)], [D.y1 D.y1-10*e_vec(2,2)], "color", "r"); 
                    end

                 newplease = replace(string(f_2),"x3", "x");
                 newplease2 = replace(newplease,"y3", "y");
                 app.center.Text = char(R);
                 app.vectors.Text =  string((e_vec(:,1))) ;
                 app.vectors_2.Text = string((e_vec(:,2)));
                 if app.CheckBox.Value
                 app.canonic.Text = newplease2 + " = " + string(f_3);
                % app.f3v.Text = " = " + string(f_3);
                 end
                 ax = app.Axes;
             ax.XAxisLocation = 'origin';
             ax.YAxisLocation = 'origin';
%                  set(app.Axes, gca, 'XAxisLocation', 'origin');
%                  set(app.Axes, gca, 'YAxisLocation', 'origin');
                     hold(app.Axes, 'off');
            end
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Общее уравнение кривой второго порядка:';
            app.xyEditField_2Label = uilabel(app.UIFigure);
            app.xyEditField_2Label.Text = 'xy +';
            app.a2 = uieditfield(app.UIFigure, 'numeric');
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y^2 +';
            app.a3 = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x +';
            app.a4 = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y +';
            app.a5 = uieditfield(app.UIFigure, 'numeric');
            app.a6 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '= 0';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Определить кривую';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Базисные вектора:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Центр:';
            app.vectors = uilabel(app.UIFigure);
            app.vectors.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Тип кривой:';
            app.type = uilabel(app.UIFigure);
            app.type.Text = '';
            app.x2Label = uilabel(app.UIFigure);
            app.x2Label.Text = 'x^2 +';
            app.a1 = uieditfield(app.UIFigure, 'numeric');
            app.canonic = uilabel(app.UIFigure);
            app.canonic.Text = '';
            app.center = uilabel(app.UIFigure);
            app.center.Text = '';
            app.vectors_2 = uilabel(app.UIFigure);
            app.vectors_2.Text = '';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = 'Каноническое уравнение:';
            app.f3v = uilabel(app.UIFigure);
            app.f3v.Text = '';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'График кривой')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end