classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button       matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        surename     matlab.ui.control.EditField
        name         matlab.ui.control.EditField
        otchestvo    matlab.ui.control.EditField
        subject1     matlab.ui.control.EditField
        subject2     matlab.ui.control.EditField
        subject3     matlab.ui.control.EditField
        EditField_7  matlab.ui.control.NumericEditField
        EditField_8  matlab.ui.control.NumericEditField
        EditField_9  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        summa        matlab.ui.control.Label
        otvet        matlab.ui.control.Label
        Label_5      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.EditField_7.Value>100 || app.EditField_8.Value>100 || app.EditField_9.Value >100
                  errordlg('Проверьте правильность введенных баллов')
            else
                sum = app.EditField_7.Value + app.EditField_8.Value + app.EditField_9.Value;
                sum2 = string(sum);
                app.summa.Text = sum2;
                if sum>=250 
                app.otvet.Text = "Поздравляем!!";
                else
                app.otvet.Text = ":(( В следующий раз";    
                end
            end
%             if (app.EditField_7.Value && app.EditField_8.Value && app.EditField_9.Value <100) && (app.EditField_7.Value && app.EditField_8.Value && app.EditField_9.Value >0) 
%             sum = app.EditField_7.Value + app.EditField_8.Value + app.EditField_9.Value;
%             sum2 = string(sum);
%             app.summa.Text = sum2;
%             if sum>=250 
%             app.otvet.Text = "Поздравляем!!";
%             else
%             app.otvet.Text = ":(( В следующий раз";    
%             end
%            
%             else
%                   warndlg('Проверьте правильность введенных баллов', 'Ошибка')
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Расчитать';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, имя, отчество';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.surename = uieditfield(app.UIFigure, 'text');
            app.name = uieditfield(app.UIFigure, 'text');
            app.otchestvo = uieditfield(app.UIFigure, 'text');
            app.subject1 = uieditfield(app.UIFigure, 'text');
            app.subject2 = uieditfield(app.UIFigure, 'text');
            app.subject3 = uieditfield(app.UIFigure, 'text');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Сумма баллов:';
            app.summa = uilabel(app.UIFigure);
            app.summa.Text = '';
            app.otvet = uilabel(app.UIFigure);
            app.otvet.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Проходной балл: 250';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end