classdef app2real < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button         matlab.ui.control.Button
        FunctionPanel  matlab.ui.container.Panel
        sinXCheckBox   matlab.ui.control.CheckBox
        cosXCheckBox   matlab.ui.control.CheckBox
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            theta = 0:0.01:4*pi;
           if app.sinXCheckBox.Value
           hold(app.UIAxes, 'on');
            rho = sin(theta);

            plot(app.UIAxes,theta,rho)
            hold(app.UIAxes, 'off');
           end
           
         
           if app.cosXCheckBox.Value
              hold(app.UIAxes, 'on');            
            rho = cos(theta);
 
            plot(app.UIAxes,theta,rho)
            hold(app.UIAxes, 'off');
           end
           if not (app.sinXCheckBox.Value)
            cla(app.UIAxes)
           end
            if not (app.cosXCheckBox.Value)
            cla(app.UIAxes)
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить график';
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Title = 'Function';
            app.sinXCheckBox = uicheckbox(app.FunctionPanel);
            app.sinXCheckBox.Text = 'sinX';
            app.cosXCheckBox = uicheckbox(app.FunctionPanel);
            app.cosXCheckBox.Text = 'cosX';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graphic')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2real

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end